classdef CompoundInterest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        YearsEditFieldLabel           matlab.ui.control.Label
        YearsEditField                matlab.ui.control.NumericEditField
        FinaldepositEditFieldLabel    matlab.ui.control.Label
        FinaldepositEditField         matlab.ui.control.NumericEditField
        InterestRateAnnualEditField   matlab.ui.control.NumericEditField
        InterestRateAnnualEditFieldLabel  matlab.ui.control.Label
        InitialDepositEditFieldLabel  matlab.ui.control.Label
        InitialDepositEditField       matlab.ui.control.NumericEditField
        CalculateButton               matlab.ui.control.Button
        Knob                          matlab.ui.control.DiscreteKnob
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function CalculateInterest(app)
            format longG;
            years = app.YearsEditField.Value;
            timeStep = str2num(app.Knob.Value);
            timeInterval = years*timeStep;
            initialBalance = app.InitialDepositEditField.Value; 
            interestRate = app.InterestRateAnnualEditField.Value./timeStep;
            stepBalances = [initialBalance];
            stepBalance = initialBalance
            for i = 1:timeInterval
                stepBalance = stepBalance.*(1 + (interestRate))
                stepBalances = [stepBalances,stepBalance];
            end
            bar(app.UIAxes,stepBalances);
            app.FinaldepositEditField.Value = stepBalances(end);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CalculateButton, 
        % InitialDepositEditField, InterestRateAnnualEditField, 
        % Knob, YearsEditField
        function CalculateButtonPushed(app, event)
            CalculateInterest(app);
        end

        % Callback function
        function InputPanelSizeChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Balance')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.Items = {'Daily', 'Weekly', 'Monthly', 'Annually'};
            app.Knob.ItemsData = {'365', '54', '12', '1'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.Knob.Value = '12';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.InitialDepositEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialDepositEditField.Limits = [0 Inf];
            app.InitialDepositEditField.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.InitialDepositEditField.Value = 100;
            app.InitialDepositEditFieldLabel = uilabel(app.UIFigure);
            app.InitialDepositEditFieldLabel.Text = 'Initial Deposit';
            app.InterestRateAnnualEditFieldLabel = uilabel(app.UIFigure);
            app.InterestRateAnnualEditFieldLabel.Text = 'Interest Rate(Annual)';
            app.InterestRateAnnualEditField = uieditfield(app.UIFigure, 'numeric');
            app.InterestRateAnnualEditField.Limits = [0 Inf];
            app.InterestRateAnnualEditField.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.InterestRateAnnualEditField.Value = 0.05;
            app.FinaldepositEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinaldepositEditField.Limits = [0 Inf];
            app.FinaldepositEditField.ValueDisplayFormat = '%.2f';
            app.FinaldepositEditField.Editable = 'off';
            app.FinaldepositEditFieldLabel = uilabel(app.UIFigure);
            app.FinaldepositEditFieldLabel.Text = 'Final deposit';
            app.YearsEditField = uieditfield(app.UIFigure, 'numeric');
            app.YearsEditField.Limits = [0 Inf];
            app.YearsEditField.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.YearsEditField.Value = 4;
            app.YearsEditFieldLabel = uilabel(app.UIFigure);
            app.YearsEditFieldLabel.Text = 'Years';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CompoundInterest

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end